classdef manual_sorting_03252025 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeSortingDisplayUIFigure    matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        NofileloadedLamp               matlab.ui.control.Lamp
        NofileloadedLampLabel          matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        FileTab                        matlab.ui.container.Tab
        exportButton                   matlab.ui.control.Button
        loadfilesButton                matlab.ui.control.Button
        matfilePanel                   matlab.ui.container.Panel
        matfileTextArea                matlab.ui.control.TextArea
        matfileTextAreaLabel           matlab.ui.control.Label
        matfileselectionButton         matlab.ui.control.Button
        spkFilePanel                   matlab.ui.container.Panel
        spkfilefolderTextArea          matlab.ui.control.TextArea
        spkfileLabel                   matlab.ui.control.Label
        spkfileselectionButton         matlab.ui.control.Button
        excelfilePanel                 matlab.ui.container.Panel
        excelfileTextArea              matlab.ui.control.TextArea
        excelfileTextAreaLabel         matlab.ui.control.Label
        excelfileselectionButton       matlab.ui.control.Button
        SortingTab                     matlab.ui.container.Tab
        SortingUnitControls            matlab.ui.container.Panel
        selectfromPCAButton            matlab.ui.control.Button
        clearselectedwaveformsButton   matlab.ui.control.Button
        actionButtonGroup              matlab.ui.container.ButtonGroup
        excludeButton                  matlab.ui.control.Button
        addunitButton                  matlab.ui.control.Button
        selectwaveformsButton          matlab.ui.control.Button
        SortingElectrodeControls       matlab.ui.container.Panel
        unsortedCheckBox               matlab.ui.control.CheckBox
        unit10CheckBox                 matlab.ui.control.CheckBox
        unit9CheckBox                  matlab.ui.control.CheckBox
        unit8CheckBox                  matlab.ui.control.CheckBox
        unit7CheckBox                  matlab.ui.control.CheckBox
        unit6CheckBox                  matlab.ui.control.CheckBox
        unit5CheckBox                  matlab.ui.control.CheckBox
        unit4CheckBox                  matlab.ui.control.CheckBox
        unit3CheckBox                  matlab.ui.control.CheckBox
        unit2CheckBox                  matlab.ui.control.CheckBox
        unit1CheckBox                  matlab.ui.control.CheckBox
        ReloadElectrodeButton          matlab.ui.control.Button
        electrodeDropDown              matlab.ui.control.DropDown
        electrodeDropDownLabel         matlab.ui.control.Label
        ParametersTab                  matlab.ui.container.Tab
        applyButton                    matlab.ui.control.Button
        NetworkBurstsPanel             matlab.ui.container.Panel
        minelectrodesparticipatingEditField  matlab.ui.control.NumericEditField
        minelectrodesparticipatingEditFieldLabel  matlab.ui.control.Label
        maxISImsEditField              matlab.ui.control.NumericEditField
        maxISImsEditFieldLabel         matlab.ui.control.Label
        minofspikesEditField           matlab.ui.control.NumericEditField
        minofspikesEditFieldLabel      matlab.ui.control.Label
        SingleElectrodeBurstsPanel     matlab.ui.container.Panel
        maxISImselectrodeEditField     matlab.ui.control.NumericEditField
        maxISImselectrodeEditFieldLabel  matlab.ui.control.Label
        minofspikeselectrodeEditField  matlab.ui.control.NumericEditField
        minofspikeselectrodeLabel      matlab.ui.control.Label
        Panel2                         matlab.ui.container.Panel
        flagthresholdEditField         matlab.ui.control.NumericEditField
        flagthresholdEditFieldLabel    matlab.ui.control.Label
        standarddeviationcutoffEditField  matlab.ui.control.NumericEditField
        standarddeviationcutoffEditFieldLabel  matlab.ui.control.Label
        cutofffrequencyHzEditField     matlab.ui.control.NumericEditField
        cutofffrequencyHzEditFieldLabel  matlab.ui.control.Label
        refractoryTsTextArea           matlab.ui.control.TextArea
        refractoryTsTextAreaLabel      matlab.ui.control.Label
        threshold_to_mergeTextArea     matlab.ui.control.TextArea
        threshold_to_mergeTextAreaLabel  matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UIAxes_selectedWaveforms       matlab.ui.control.UIAxes
        UIAxes_selection               matlab.ui.control.UIAxes
        UIAxes_PCA                     matlab.ui.control.UIAxes
        UIAxes_spikes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % The app displays the data by using the scatter plot, histogram, and table.
    % It makes use of tabs to separate the ploting options output from the table display of the data.
    % There are several graphical elements used such as checkboxes, slider, switch, dropdown, and radiobutton group.
    % The data used in the app is shipped with the product.

    properties (Access = private)
        % Declare properties of the PatientsDisplay class.
        allData % spk file data
        T % excel file table
        electrodeUnitsNames
        maxTime
        threshold_to_merge
        selectedUnits
        currSelectedUnits
        spikes
        W_proj
        new_idx_list
        selectedElectrodeName
        Times
        refractoryT
        raster_raw
        burst_info_all
        fr
        plotWaveforms
        plotPCAs
        SelectedWaveforms
        updatedElectrodes % {ElectrodeName;new_idx_list}cell array: store electrode that has been cured by user, electrodeName, new_idx_list
        unsortedWFs
        sorting_results
        original_sorting_results
        cutofffrequency
        selectionPlotHandles
        pcaPlotHandles
        pcaLastSelection
        colors = {
                    [1, 0, 0],       % red
                    [0, 0.7, 0],     % green
                    [0, 0, 1],       % blue
                    [0, 0.8, 0.8],   % cyan
                    [1, 0, 1],       % magenta
                    [0.8, 0.8, 0],   % yellow
                    [0.30,0.75,0.93],% black
                    [1, 0.5, 0],     % orange
                    [0.5, 0, 0.5],   % purple
                    [0, 0.5, 0]      % dark green
                    };
        checkBoxes
        SPK_dataset % basically a file pointer used to access the relevant .spk file
    end

    methods (Access = private)

% ------------------------------------------------------------------------------------------------------------
        function performPlot(app)
            % Cluster the selected electrode and plot the spikes and PCA

            % Ensure we're respecting the 10-unit maximum
            if length(app.new_idx_list) > 10
                % Keep only the first 10 units
                app.new_idx_list = app.new_idx_list(1:10);
                warning('Maximum number of units (10) reached. Keeping only the first 10 units.');
            end

            % Perform SVD for dimensionality reduction
            [U, ~, ~] = svd(app.spikes);
            U2 = U(:, 1:2);
            app.W_proj = U2' * app.spikes;

            % Clear existing plots
            cla(app.UIAxes_PCA);
            cla(app.UIAxes_spikes);
            cla(app.UIAxes_selection);
            cla(app.UIAxes_selectedWaveforms);

            % Initialize plot containers
            app.plotWaveforms = cell(1, length(app.new_idx_list) + 1);
            app.plotPCAs = cell(1, length(app.new_idx_list) + 1);

            % Plot waveforms
            hold(app.UIAxes_spikes, 'on');

            % Plot sorted waveforms
            for unit_ = 1:length(app.new_idx_list)
                app.plotWaveforms{unit_} = plot(app.UIAxes_spikes, app.spikes(:, app.new_idx_list{unit_}), 'Color', app.colors{unit_});
            end

            % Plot unsorted waveforms if they exist
            unsortedUnitIndex = length(app.new_idx_list) + 1;
            if ~isempty(app.unsortedWFs)
                app.plotWaveforms{unsortedUnitIndex} = plot(app.UIAxes_spikes, app.spikes(:, app.unsortedWFs), 'Color', [0.7, 0.7, 0.7, 0.5]);
            end

            hold(app.UIAxes_spikes, 'off');

            % Plot PCA projections
            hold(app.UIAxes_PCA, "on");

            % Plot sorted units in PCA space
            % Create storage for plot handles if it doesn't exist
            if ~isfield(app, 'pcaPlotHandles')
                app.pcaPlotHandles = cell(1, length(app.new_idx_list) + 1);
            end

            % plot PCA projections with corresponding unit colors
            for unit_ = 1:length(app.new_idx_list)
                app.plotPCAs{unit_} = plot(app.UIAxes_PCA, ...
                    app.W_proj(1, app.new_idx_list{unit_}), ...
                    app.W_proj(2, app.new_idx_list{unit_}), ...
                    '.', 'Color', app.colors{unit_}, ...
                    'MarkerSize', 3);
            end

            % Plot unsorted units in PCA space if they exist
            if ~isempty(app.unsortedWFs)
                app.plotPCAs{unsortedUnitIndex} = plot(app.UIAxes_PCA, ...
                    app.W_proj(1, app.unsortedWFs), ...
                    app.W_proj(2, app.unsortedWFs), ...
                    '.', 'Color', [0.7, 0.7, 0.7, 0.5], ...
                    'MarkerSize', 3);
            end

            hold(app.UIAxes_PCA, 'off');

            % % Clear selection status
            % app.currSelectedUnits = [];
            % app.SelectedWaveforms = cell(1, length(app.new_idx_list) + 1);

        end

% ------------------------------------------------------------------------------------------------------------
        function updateRasterRaw(app)
            % Parse electrode name to get well and electrode coordinates
            myElectrodeName = split(app.selectedElectrodeName, "_");
            wellInfo = myElectrodeName{1};
            electrodeInfo = myElectrodeName{2};

            % Get well coordinates (A1 -> row=1, col=1)
            nwr = wellInfo(1) - 'A' + 1;  % Well row (A->1, B->2, etc)
            nwc = str2double(wellInfo(2:end));  % Well column

            % Get electrode coordinates
            nec = str2double(electrodeInfo(1));  % Electrode column
            ner = str2double(electrodeInfo(2));  % Electrode row

            % Search for matching electrodes across all wells
            targetWell = 0;
            targetIndices = [];

            % Iterate through wells to find matching coordinates
            for wellIdx = 1:size(app.raster_raw, 3)
                % Get all electrode indices for current well
                wellElectrodes = cell2mat(app.raster_raw(:,2,wellIdx));

                % Find electrodes matching our coordinates
                if ~isempty(wellElectrodes)
                    matches = all(wellElectrodes(:,1:4) == [nwr nwc nec ner], 2);
                    if any(matches)
                        targetWell = wellIdx;
                        targetIndices = find(matches);
                        break;
                    end
                end
            end

            % If no matching electrodes found, return
            if targetWell == 0
                return;
            end

            % Update T_network
            T_network = [];
            if ~isempty(app.new_idx_list)        
                % store time of spikes
                for unitIdx = 1:length(app.new_idx_list)
                    T_network = [T_network app.Times(1,app.new_idx_list{unitIdx})];
                end
            end

            app.raster_raw{targetIndices,1,targetWell} =sort(T_network);

        end


% ------------------------------------------------------------------------------------------------------------
        function removeEmptyCells(app)
            % remove empty cells from app.new_idx_list
            copyList = {};
            for i = 1:length(app.new_idx_list)
                if ~isempty(app.new_idx_list{i})
                    copyList{end+1} = app.new_idx_list{i};
                end
            end
            app.new_idx_list = copyList;
        end
        % ------------------------------------------------------------------------------------------------------------


        % Helper function to reset unit checkboxes
        function resetUnitCheckboxes(app)
            % Reset all unit selection checkboxes

            % Only enable checkboxes for existing units
            for i = 1:10
                if i <= length(app.new_idx_list)
                    app.checkBoxes{i}.Enable = 'on';
                else
                    app.checkBoxes{i}.Enable = 'off';
                end
                % Uncheck all checkboxes
                app.checkBoxes{i}.Value = false;
            end

            % Also reset the unsorted checkbox if it exists

            app.unsortedCheckBox.Value = false;


            % Clear the current selected units
            app.currSelectedUnits = [];
        end
        % ------------------------------------------------------------------------------------------------------------

        function loadElectrodeData(app)            
            % Update the spike plot and PCA plot to show electrode

            % change semaphore color/text
            app.NofileloadedLamp.Color = [1 1 0];
            app.NofileloadedLampLabel.Text = 'Loading electrode...';
            
            % disable interaction with sorting electrode controls while loading
            app.SortingElectrodeControls.Enable = 'off';

            % disable interaction with sorting unit controls until unit
            % selected
            app.SortingUnitControls.Enable = 'off';
            
            drawnow;
            myElectrodeNameSplitted = split(app.selectedElectrodeName,"_");
            nwr = (myElectrodeNameSplitted{1}(1)-'A'+1);
            nwc = str2num(myElectrodeNameSplitted{1}(2:size(myElectrodeNameSplitted{1},2)));
            nec = str2num(myElectrodeNameSplitted{2}(1));
            ner = str2num(myElectrodeNameSplitted{2}(2));
            % load spikes for this electrode
            electrodeData = app.SPK_dataset.LoadData(myElectrodeNameSplitted{1}, myElectrodeNameSplitted{2});
            Spikes = electrodeData{nwr,nwc,nec,ner}(:);

            % do the cluster
            [app.Times,app.spikes] = Spikes.GetTimeVoltageVector;
            app.new_idx_list = app.sorting_results{nwr,nwc,nec,ner,1};
            app.unsortedWFs = app.sorting_results{nwr,nwc,nec,ner,2};
            performPlot(app);
            updateCheckboxStates(app);
            resetUnitCheckboxes(app)

            % change semaphore color/text
            app.NofileloadedLamp.Color = [0 1 0];
            app.NofileloadedLampLabel.Text = 'Electrode loaded';

            % enable interaction with sorting controls
            app.SortingElectrodeControls.Enable = 'on';

            drawnow;
        end

        % Function to update checkbox states based on actual data
        function updateCheckboxStates(app)
            % Enable/disable unit checkboxes based on existing units
            for i = 1:10
                if i <= length(app.new_idx_list)
                    app.checkBoxes{i}.Enable = 'on';
                    app.checkBoxes{i}.Text = "unit "+i+" ("+length(app.new_idx_list{i})+")";
                else
                    app.checkBoxes{i}.Enable = 'off';
                    app.checkBoxes{i}.Text = "unit "+i;
                end
                % Uncheck all checkboxes
                app.checkBoxes{i}.Value = false;
            end

            % Update unsorted checkbox based on whether unsorted waveforms exist

            if ~isempty(app.unsortedWFs)
                app.unsortedCheckBox.Enable = 'on';
                app.unsortedCheckBox.Text = "unsorted ("+length(app.unsortedWFs)+")";
            else
                app.unsortedCheckBox.Enable = 'off';
                app.unsortedCheckBox.Text = "unsorted";
                
            end
            app.unsortedCheckBox.Value = false;

        end
        % ------------------------------------------------------------------------------------------------------------
        function updateElectrode(app)
            myElectrodeNameSplitted = split(app.selectedElectrodeName,"_");
            nwr = (myElectrodeNameSplitted{1}(1)-'A'+1);
            nwc = str2num(myElectrodeNameSplitted{1}(2:size(myElectrodeNameSplitted{1},2)));
            nec = str2num(myElectrodeNameSplitted{2}(1));
            ner = str2num(myElectrodeNameSplitted{2}(2));
            app.sorting_results{nwr,nwc,nec,ner,1} = app.new_idx_list;
            app.sorting_results{nwr,nwc,nec,ner,2} = app.unsortedWFs;
        end
        % ------------------------------------------------------------------------------------------------------------
        % update the selection plot
        function updateSelectionPlot(app)
            % Clear the selection plot
            cla(app.UIAxes_selection);

            % Exit if no units selected
            if isempty(app.currSelectedUnits)
                title(app.UIAxes_selection, 'No units selected');
                return;
            end

            % Plot waveforms from selected units
            hold(app.UIAxes_selection, 'on');

            % Create storage for plot handles if it doesn't exist
            if ~isfield(app, 'selectionPlotHandles')
                app.selectionPlotHandles = cell(1, length(app.new_idx_list) + 1);
            end

            % Plot each selected unit with its corresponding color
            for i = 1:length(app.currSelectedUnits)
                unitIndex = app.currSelectedUnits(i);

                if unitIndex <= length(app.new_idx_list) && ~isempty(app.new_idx_list{unitIndex})
                    % Plot sorted units
                    waveformIndices = app.new_idx_list{unitIndex};
                    plot(app.UIAxes_selection, 1:size(app.spikes,1), app.spikes(:, waveformIndices), 'Color', app.colors{unitIndex});
                elseif unitIndex > length(app.new_idx_list) && ~isempty(app.unsortedWFs)
                    % This is the critical part for unsorted waveforms
                    % The unsorted unit index is beyond the regular units
                    for j = 1:length(app.unsortedWFs)
                        plot(app.UIAxes_selection, 1:size(app.spikes, 1), app.spikes(:, app.unsortedWFs(j)), 'Color', [0.7, 0.7, 0.7]);
                    end
                end
            end

            % Set plot title to indicate which units are shown
            title(app.UIAxes_selection, ['Selected Units: ' num2str(app.currSelectedUnits)]);
            hold(app.UIAxes_selection, 'off');
        end

        
        % ----------------end of methods section-------------------------------
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updatedElectrodes = {};
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpikeSortingDisplayUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {957, 957};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: spkfileselectionButton
        function spkfileselectionButtonPushed(app, event)
            [file,path] = uigetfile('*.spk');
            if isequal(file,0)
                disp('User selection Cancelled');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            app.spkfilefolderTextArea.Value = fullfile(path,file);
        end

        % Value changing function: spkfilefolderTextArea
        function spkfilefolderTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.spkfilefolderTextArea.Value = changingValue;
        end

        % Value changing function: threshold_to_mergeTextArea
        function threshold_to_mergeTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.threshold_to_mergeTextArea.Value = changingValue;
        end

        % Button pushed function: excelfileselectionButton
        function excelfileselectionButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            if isequal(file,0)
                disp('User selection Cancelled');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            app.excelfileTextArea.Value = fullfile(path,file);
        end

        % Value changing function: excelfileTextArea
        function excelfileTextAreaValueChanging(app, event)

        end

        % Button pushed function: loadfilesButton
        function loadfilesButtonPushed(app, event)
            % change semaphore text/color
            app.NofileloadedLamp.Color = [1 1 0];
            app.NofileloadedLampLabel.Text = 'File loading...';

            % temporarily disable sorting controls (except electrode
            % selection)
            app.SortingElectrodeControls.Enable = 'off';
            app.SortingUnitControls.Enable = 'off';

            drawnow;
            app.threshold_to_merge = str2num(app.threshold_to_mergeTextArea.Value{1});
            app.refractoryT = str2num(app.refractoryTsTextArea.Value{1});
            app.cutofffrequency = app.cutofffrequencyHzEditField.Value;
            %load excel file
            excelFullPathName = app.excelfileTextArea.Value{1};
            app.T = readtable(excelFullPathName,'Sheet','individual unit');
            % load raster raw
            matFullPathName = app.matfileTextArea.Value{1};
            matfileStruct = load(matFullPathName);
            app.raster_raw = matfileStruct.raster_raw;
            app.burst_info_all = matfileStruct.burst_info_all;
            app.maxTime = matfileStruct.maxTime;
            app.sorting_results = matfileStruct.sorting_results;
            app.original_sorting_results = matfileStruct.sorting_results;
            % read electrode names
            spkFilePathName = app.spkfilefolderTextArea.Value{1};
            app.SPK_dataset =  AxisFile(spkFilePathName).SpikeData;
            app.fr = app.SPK_dataset.SamplingFrequency;
            electrodeNames = app.T.Properties.VariableNames(1,2:size(app.T,2));
            app.electrodeUnitsNames = electrodeNames;
            for i = 1:length(electrodeNames)
                electrodeNames{i} = electrodeNames{i}(1:end-2);
            end
            electrodeNames = [{'Select...'}, electrodeNames];
            electrodeNames = unique(electrodeNames, 'stable');
            app.electrodeDropDown.Items = electrodeNames;
            app.checkBoxes = {app.unit1CheckBox, app.unit2CheckBox, app.unit3CheckBox, app.unit4CheckBox, app.unit5CheckBox,...
                app.unit6CheckBox, app.unit7CheckBox, app.unit8CheckBox, app.unit9CheckBox, app.unit10CheckBox};
            app.NofileloadedLamp.Color = [0 0 1];
            app.NofileloadedLampLabel.Text = 'File loaded';
            app.electrodeDropDown.Placeholder = 'Select an electrode';
            drawnow
        end

        % Value changed function: electrodeDropDown
        function electrodeDropDownValueChanged(app, event)
            if app.electrodeDropDown.Value == "Select..."
                return;
            end

            % Update the selected electrode name
            app.selectedElectrodeName = app.electrodeDropDown.Value;

            % Clear any previous selections
            app.currSelectedUnits = [];
            app.SelectedWaveforms = {};
            % Load the electrode data
            loadElectrodeData(app);
        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)

            app.threshold_to_merge = str2num(app.threshold_to_mergeTextArea.Value{1});
            app.refractoryT = str2num(app.refractoryTsTextArea.Value{1});
            app.cutofffrequency = app.cutofffrequencyHzEditField.Value;
        end

        % Value changing function: refractoryTsTextArea
        function refractoryTsTextAreaValueChanging(app, event)
            changingValue = event.Value;
            app.refractoryTsTextArea.Value = changingValue;
        end

        % Button pushed function: matfileselectionButton
        function matfileselectionButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('User selection Cancelled');
            else
                disp(['User selected ', fullfile(path,file)]);
            end
            app.matfileTextArea.Value = fullfile(path,file);
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            % Get burst parameters from the UI
            network_participation_threshold = app.minelectrodesparticipatingEditField.Value;
            min_spikes_E = app.minofspikeselectrodeEditField.Value;
            max_ISI_E = app.maxISImselectrodeEditField.Value;
            min_spikes_N = app.minofspikesEditField.Value;
            max_ISI_N = app.maxISImsEditField.Value;
            cutoff_frequency = app.cutofffrequencyHzEditField.Value;

            % 1. Allow the user to select output folder path
            outputFolder = uigetdir(pwd, 'Select Output Folder');
            if outputFolder == 0
                % User canceled the folder selection
                msgbox('Export canceled: No output folder selected', 'Export Canceled', 'warn');
                return;
            end

            % Create 'adjusted spike sorting' subfolder in the selected directory
            subfolder = 'adjusted spike sorting';
            outputPath = fullfile(outputFolder, subfolder);
            if ~exist(outputPath, 'dir')
                mkdir(outputPath);
            end

            % Perform network burst analysis
            get_network_burst_info(app.raster_raw, app.maxTime, app.fr, network_participation_threshold,...
                min_spikes_E, max_ISI_E, min_spikes_N, max_ISI_N, outputPath, app.sorting_results);

            % Get individual unit statistics and electrode-level statistics
            app.allData = app.SPK_dataset.LoadData;
            [T_unit, T_electrode] = get_individual_unit_analysis(app.sorting_results, app.allData, app.maxTime, ...
                cutoff_frequency, max_ISI_E, min_spikes_E, app.refractoryT, outputPath);

            % Save results to Excel
            writetable(T_unit, fullfile(outputPath, 'spike_sorting.xlsx'), 'Sheet', 'individual unit');
            writetable(T_electrode, fullfile(outputPath, 'spike_sorting.xlsx'), 'Sheet', 'electrode statistics');

            % % Generate PowerPoint summary if requested
            % generate_ppt_summary(app, outputPath);

            % Show completion message
            msgbox('Export completed successfully!', 'Export Complete', 'help');

        end

        % Button pushed function: selectwaveformsButton
        function selectwaveformsButtonPushed(app, event)
            % Check if any units are selected via checkboxes
            if isempty(app.currSelectedUnits)
                msgfig = msgbox('Please select one or more units first by checking the unit boxes!', 'error', 'modal');
                uiwait(msgfig);
                return;
            end

            % Let user draw line on the selection plot
            uLineDraw = drawline(app.UIAxes_selection);
            uLineX = uLineDraw.Position(:,1);
            uLineY = uLineDraw.Position(:,2);
            uLine = [uLineX'; uLineY'];

            % Initialize selected waveforms container (considering max 10 units + unsorted)
            app.SelectedWaveforms = cell(1, 11);

            % Clear the selected waveforms plot
            cla(app.UIAxes_selectedWaveforms);
            hold(app.UIAxes_selectedWaveforms, 'on');

            % Track if any waveforms were selected
            anyWaveformsSelected = false;

            % Only process units that are selected via checkboxes
            for i = 1:length(app.currSelectedUnits)
                unitIndex = app.currSelectedUnits(i);

                % Handle sorted units
                if unitIndex <= length(app.new_idx_list) && unitIndex <= 10
                    A = app.spikes(:, app.new_idx_list{unitIndex});
                    selectedLines = [];

                    % Check for crossed lines with waveforms in this unit
                    for idx = 1:size(A, 2)
                        L2 = [1:size(A, 1); A(:, idx)'];
                        P = InterX(uLine, L2);

                        if ~isempty(P)
                            selectedLines(end+1) = idx;

                            % Plot selected waveform in the selected waveforms plot
                            plot(app.UIAxes_selectedWaveforms, 1:size(A, 1), A(:, idx), 'Color', app.colors{unitIndex});
                            anyWaveformsSelected = true;
                        end
                    end

                    app.SelectedWaveforms{unitIndex} = selectedLines;
                end

                % Handle unsorted waveforms if they're selected
                unsortedUnitIndex = length(app.new_idx_list) + 1;
                if unitIndex == unsortedUnitIndex && ~isempty(app.unsortedWFs)
                    A = app.spikes(:, app.unsortedWFs);
                    selectedLines = [];

                    % Check for crossed lines with unsorted waveforms
                    for idx = 1:size(A, 2)
                        L2 = [1:size(A, 1); A(:, idx)'];
                        P = InterX(uLine, L2);

                        if ~isempty(P)
                            selectedLines(end+1) = idx;

                            % Plot selected waveform in the selected waveforms plot
                            plot(app.UIAxes_selectedWaveforms, 1:size(A, 1), A(:, idx), 'Color', [0.7, 0.7, 0.7, 0.5]);
                            anyWaveformsSelected = true;


                        end
                    end

                    app.SelectedWaveforms{unsortedUnitIndex} = selectedLines;
                end
            end

            % Complete the selectedWaveforms plot
            hold(app.UIAxes_selectedWaveforms, 'off');

            if anyWaveformsSelected
                title(app.UIAxes_selectedWaveforms, 'Selected Waveforms');

                % Update status message with selection counts
                totalSelected = 0;
                for i = 1:length(app.SelectedWaveforms)
                    if i <= length(app.SelectedWaveforms)
                        totalSelected = totalSelected + length(app.SelectedWaveforms{i});
                    end
                end
            else
                title(app.UIAxes_selectedWaveforms, 'No Waveforms Selected');
            end

            % Highlight the selection line
            delete(uLineDraw);
            hold(app.UIAxes_selection, 'on');
            plot(app.UIAxes_selection, uLineX, uLineY, 'r', 'LineWidth', 2);
            hold(app.UIAxes_selection, 'off');
        end

        % Button pushed function: addunitButton
        function addunitButtonPushed(app, event)
            % Check if any waveforms are selected
            hasSelectedWaveforms = false;
            for i = 1:length(app.SelectedWaveforms)
                if ~isempty(app.SelectedWaveforms{i})
                    hasSelectedWaveforms = true;
                    break;
                end
            end

            if ~hasSelectedWaveforms
                msgfig = msgbox('No waveforms have been selected! First select units via checkboxes, then draw a line to select waveforms.', 'error', 'modal');
                uiwait(msgfig);
                return;
            end

            % Gather all selected waveform indices
            selectedWaveformsFromUnits = cell(length(app.new_idx_list), 1);
            selectedUnsortedWaveforms = [];

            % Get waveform indices from sorted units
            for unit_ = 1:length(app.new_idx_list)
                if unit_ <= length(app.SelectedWaveforms) && ~isempty(app.SelectedWaveforms{unit_})
                    selectedWaveformsFromUnits{unit_} = app.new_idx_list{unit_}(app.SelectedWaveforms{unit_});
                end
            end

            % Get waveform indices from unsorted waveforms
            unsortedUnitIndex = length(app.new_idx_list) + 1;
            if unsortedUnitIndex <= length(app.SelectedWaveforms) && ~isempty(app.unsortedWFs) && ~isempty(app.SelectedWaveforms{unsortedUnitIndex})
                selectedUnsortedWaveforms = app.unsortedWFs(app.SelectedWaveforms{unsortedUnitIndex});
            end

            % Create options list for dropdown dialog
            options = cell(length(app.new_idx_list) + 1, 1);
            for i = 1:length(app.new_idx_list)
                options{i} = ['Add to Unit ' num2str(i)];
            end
            options{end} = 'Create New Unit';

            % Check if we can create a new unit (max 10)
            if length(app.new_idx_list) >= 10
                options = options(1:end-1);  % Remove "Create New Unit" option
            end

            % Show dropdown dialog for user to choose
            [indx, tf] = listdlg('ListString', options, 'SelectionMode', 'single', ...
                'Name', 'Add to Unit', 'PromptString', 'Select destination unit:');

            if ~tf
                % User canceled
                return;
            end

            % disable interaction with sorting unit controls
            app.SortingUnitControls.Enable = 'off';
            drawnow;

            % Process based on selection
            if indx <= length(app.new_idx_list)
                % Add to existing unit
                targetUnit = indx;

                % Collect all selected waveforms
                allSelectedWaveforms = [];

                % Add waveforms from units
                for unit_ = 1:length(app.new_idx_list)
                    if ~isempty(selectedWaveformsFromUnits{unit_})
                        if unit_ ~= targetUnit  % Don't process target unit's own waveforms
                            allSelectedWaveforms = [allSelectedWaveforms; selectedWaveformsFromUnits{unit_}];

                            % Remove selected waveforms from original units
                            updatedUnit = setdiff(app.new_idx_list{unit_}, selectedWaveformsFromUnits{unit_});
                            app.new_idx_list{unit_} = updatedUnit;
                        end
                    end
                end

                % Add unsorted waveforms
                if ~isempty(selectedUnsortedWaveforms)
                    allSelectedWaveforms = [allSelectedWaveforms; selectedUnsortedWaveforms'];

                    % Remove selected waveforms from unsorted
                    updatedUnsorted = setdiff(app.unsortedWFs, selectedUnsortedWaveforms);
                    app.unsortedWFs = updatedUnsorted;
                end

                % Add to target unit
                app.new_idx_list{targetUnit} = [app.new_idx_list{targetUnit}; allSelectedWaveforms];
            else
                % Create new unit

                % Collect all selected waveforms
                newUnit = [];

                % Add waveforms from units
                for unit_ = 1:length(app.new_idx_list)
                    if ~isempty(selectedWaveformsFromUnits{unit_})
                        newUnit = [newUnit; selectedWaveformsFromUnits{unit_}];

                        % Remove selected waveforms from original units
                        updatedUnit = setdiff(app.new_idx_list{unit_}, selectedWaveformsFromUnits{unit_});
                        app.new_idx_list{unit_} = updatedUnit;
                    end
                end

                % Add unsorted waveforms
                if ~isempty(selectedUnsortedWaveforms)
                    newUnit = [newUnit; selectedUnsortedWaveforms'];

                    % Remove selected waveforms from unsorted
                    updatedUnsorted = setdiff(app.unsortedWFs, selectedUnsortedWaveforms);
                    app.unsortedWFs = updatedUnsorted;
                end

                % Add as new unit
                app.new_idx_list{end+1} = newUnit;

            end

            % Clean up and refresh display
            removeEmptyCells(app);
            % Store the current selection before calling performPlot
            currentSelectedUnits = app.currSelectedUnits;

            performPlot(app);
            updateRasterRaw(app);
            updateElectrode(app);
            % % Clear selections
            % app.SelectedWaveforms = cell(1, 6);
            % 
            % Clear the selection plot contents
            cla(app.UIAxes_selectedWaveforms);
            title(app.UIAxes_selectedWaveforms, 'No Waveforms Selected');

            % Restore the selected units after performPlot resets them
            app.currSelectedUnits = [];

            % Update the selection plot with the current units
            updateSelectionPlot(app);
            updateCheckboxStates(app);
        end

        % Button pushed function: excludeButton
        function excludeButtonPushed(app, event)
            % Check if any waveforms are selected
            hasSelectedWaveforms = false;
            for i = 1:length(app.SelectedWaveforms)
                if ~isempty(app.SelectedWaveforms{i})
                    hasSelectedWaveforms = true;
                    break;
                end
            end

            if ~hasSelectedWaveforms
                msgfig = msgbox('No waveforms have been selected! First select units via checkboxes, then draw a line to select waveforms.', 'error', 'modal');
                uiwait(msgfig);
                return;
            end

            % temporarily disable unit sorting controls
            app.SortingUnitControls.Enable = 'off';
            drawnow;

            % Count waveforms to be excluded
            totalExcluded = 0;

            % Process selected waveforms from units
            for unit_ = 1:length(app.new_idx_list)
                if unit_ <= length(app.SelectedWaveforms) && ~isempty(app.SelectedWaveforms{unit_})
                    % Add selected waveforms to unsorted waveforms
                    selectedWaveforms = app.new_idx_list{unit_}(app.SelectedWaveforms{unit_});
                    app.unsortedWFs = [app.unsortedWFs selectedWaveforms'];
                    app.unsortedWFs
                    totalExcluded = totalExcluded + length(selectedWaveforms);

                    % Remove selected waveforms from original units
                    updatedUnit = setdiff(app.new_idx_list{unit_}, selectedWaveforms);
                    app.new_idx_list{unit_} = updatedUnit;
                end
            end

            % Clean up and refresh display
            removeEmptyCells(app);

            % Store the current selection before calling performPlot
            currentSelectedUnits = app.currSelectedUnits;

            performPlot(app);
            updateRasterRaw(app);
            updateElectrode(app);

            % Clear selections
            app.SelectedWaveforms = cell(1, 11);

            % Clear the selection plot contents
            cla(app.UIAxes_selectedWaveforms);
            title(app.UIAxes_selectedWaveforms, 'No Waveforms Selected');

            % Restore the selected units after performPlot resets them
            app.currSelectedUnits = [];
            % Update the selection plot with the current units
            updateSelectionPlot(app);
            updateCheckboxStates(app);

            % re-enable sorting controls
            app.SortingUnitControls.Enable = 'on';
            drawnow;
        end

        % Value changed function: unit10CheckBox, unit1CheckBox, 
        % ...and 9 other components
        function unit1CheckBoxValueChanged(app, event)


            % Initialize empty array for selected units
            currSelectedUnits = [];

            % check each regular unit checkbox to see whether to keep it
            for i = 1:length(app.currSelectedUnits)
                % unsorted
                if app.currSelectedUnits(i) == length(app.new_idx_list)+1 && app.unsortedCheckBox.Value && ~isempty(app.unsortedWFs)
                    currSelectedUnits = [currSelectedUnits length(app.new_idx_list)+1];
                % regular unit
                elseif app.currSelectedUnits(i) <= 10 && app.checkBoxes{app.currSelectedUnits(i)}.Value == 1
                    currSelectedUnits = [currSelectedUnits app.currSelectedUnits(i)];
                end

            end

            % check whether we need to append any units
            for i = 1:11
                % standard case (assigned units)
                if i <= length(app.new_idx_list) && app.checkBoxes{i}.Value && ~isempty(app.new_idx_list{i}) && ~any(app.currSelectedUnits(:) == i)
                    currSelectedUnits = [currSelectedUnits i];
                % edge case (unsorted)
                elseif i == length(app.new_idx_list)+1 && app.unsortedCheckBox.Value && ~any(app.currSelectedUnits(:) == i)
                    currSelectedUnits = [currSelectedUnits i ];
                end
            end

            % Update the application's current selected units
            app.currSelectedUnits = currSelectedUnits;

            % Update the selection plot to show only waveforms from selected units
            updateSelectionPlot(app);

            % Clear the selected waveforms plot when changing units
            cla(app.UIAxes_selectedWaveforms);
            title(app.UIAxes_selectedWaveforms, 'Draw line in selection plot to select waveforms');

            % Reset selection
            app.SelectedWaveforms = cell(1, length(app.new_idx_list) + 1);

            % toggle sorting unit controls based on whether units are
            % selected
            if length(app.currSelectedUnits) > 0
                app.SortingUnitControls.Enable = 'on';
            else
                app.SortingUnitControls.Enable = 'off';
            end
            drawnow;
        end

        % Button pushed function: clearselectedwaveformsButton
        function clearselectedwaveformsButtonPushed(app, event)
            % Clear all selected waveforms
            app.SelectedWaveforms = cell(1, 11);

            % Clear the selected waveforms plot
            cla(app.UIAxes_selectedWaveforms);
            title(app.UIAxes_selectedWaveforms, 'No Waveforms Selected');

            % Restore original colors in the selection plot
            if isfield(app, 'selectionPlotHandles')
                % Restore colors for all waveforms in currently selected units
                for i = 1:length(app.currSelectedUnits)
                    unitIndex = app.currSelectedUnits(i);

                    if unitIndex <= length(app.selectionPlotHandles) && ~isempty(app.selectionPlotHandles{unitIndex})
                        if unitIndex <= length(app.new_idx_list)
                            % For regular units
                            for j = 1:length(app.selectionPlotHandles{unitIndex})
                                set(app.selectionPlotHandles{unitIndex}(j), 'Color', app.colors{unitIndex}, 'LineWidth', 0.5);
                            end
                        else
                            % For unsorted waveforms
                            for j = 1:length(app.selectionPlotHandles{unitIndex})
                                set(app.selectionPlotHandles{unitIndex}(j), 'Color', [0.7, 0.7, 0.7], 'LineWidth', 0.5);
                            end
                        end
                    end
                end
            end

        end

        % Button pushed function: selectfromPCAButton
        function selectfromPCAButtonPushed(app, event)
            % this method allows selection of waveforms from PCA plot

            % check if any units are selected via checkboxes
            if isempty(app.currSelectedUnits)
                msgfig = msgbox('Please select one or more units first by checking the unit boxes!', 'error', 'modal');
                uiwait(msgfig);
                return;
            end

            % let user draw freehand selection on the PCA plot
            pca_roi = drawfreehand(app.UIAxes_PCA);

            % Initialize selected waveforms container (considering max 10 units + unsorted)
            app.SelectedWaveforms = cell(1, 11);

            % Clear the selected waveforms plot
            cla(app.UIAxes_selectedWaveforms);
            hold(app.UIAxes_selectedWaveforms, 'on');
            
            % find selected points
            pca_sel = inpolygon(app.W_proj(1,:), app.W_proj(2,:), pca_roi.Position(:,1), pca_roi.Position(:,2));

            % loop through selected units
            for i = 1:length(app.currSelectedUnits)
                unitIndex = app.currSelectedUnits(i);
                unsortedUnitIndex = length(app.new_idx_list) + 1;

                if unitIndex <= length(app.new_idx_list) && unitIndex <= 10
                    % mask both spikes and the selected points on the pca
                    % plot by unit
                    A = app.spikes(:, app.new_idx_list{unitIndex});
                    P = pca_sel(:, app.new_idx_list{unitIndex});

                    % use masked pca points to generate list of indices
                    selectedLines = nonzeros(P .* [1:length(P)]);

                    if isempty(selectedLines)
                        continue
                    end

                    % plot lines
                    plot(app.UIAxes_selectedWaveforms, 1:size(A, 1), A(:, selectedLines), 'Color', app.colors{unitIndex});

                    % add to selected waveforms list
                    app.SelectedWaveforms{unitIndex} = selectedLines;

                % Handle unsorted waveforms if they're selected
                elseif unitIndex == unsortedUnitIndex && ~isempty(app.unsortedWFs)
                    A = app.spikes(:, app.unsortedWFs);
                    P = pca_sel(:, app.unsortedWFs);

                    % use masked pca points to generate list of indices
                    selectedLines = nonzeros(P .* [1:length(P)]);

                    if isempty(selectedLines)
                        continue
                    end

                    % plot lines
                    plot(app.UIAxes_selectedWaveforms, 1:size(A, 1), A(:, selectedLines), 'Color', [0.7, 0.7, 0.7, 0.5]);

                    % add to selected waveforms list
                    app.SelectedWaveforms{unsortedUnitIndex} = selectedLines;
                end

            end

            
            % Complete the selectedWaveforms plot
            hold(app.UIAxes_selectedWaveforms, 'off');

            % clean up previous selection
            if ~isempty(app.pcaLastSelection)
                delete(app.pcaLastSelection);
            end
            app.pcaLastSelection = pca_roi;


        end

        % Button pushed function: ReloadElectrodeButton
        function ReloadElectrodeButtonPushed(app, event)
            % have user verify intent
            response = questdlg("Are you sure you want to reload this electrode? This action cannot be undone.");
            if ~strcmp(response, "Yes")
                return;
            end

            % temporarily disable sorting unit controls
            app.SortingUnitControls.Enable = 'off';
            drawnow;

            % Update the spike plot and PCA plot to show electrode
            app.NofileloadedLamp.Color = [1 1 0];
            app.NofileloadedLampLabel.Text = 'Reloading electrode...';
            drawnow;

            % reload the original sorting
            myElectrodeNameSplitted = split(app.selectedElectrodeName,"_");
            nwr = (myElectrodeNameSplitted{1}(1)-'A'+1);
            nwc = str2num(myElectrodeNameSplitted{1}(2:size(myElectrodeNameSplitted{1},2)));
            nec = str2num(myElectrodeNameSplitted{2}(1));
            ner = str2num(myElectrodeNameSplitted{2}(2));
            app.new_idx_list = app.original_sorting_results{nwr,nwc,nec,ner,1};
            app.unsortedWFs = app.original_sorting_results{nwr,nwc,nec,ner,2};

            % Clean up and refresh display
            removeEmptyCells(app);
            % Store the current selection before calling performPlot
            currentSelectedUnits = app.currSelectedUnits;

            performPlot(app);
            updateRasterRaw(app);
            updateElectrode(app);

            % Clear the selection plot contents
            cla(app.UIAxes_selectedWaveforms);
            title(app.UIAxes_selectedWaveforms, 'No Waveforms Selected');

            % Restore the selected units after performPlot resets them
            app.currSelectedUnits = [];

            % Update the selection plot with the current units
            updateSelectionPlot(app);
            updateCheckboxStates(app);

            % update the semaphore
            app.NofileloadedLamp.Color = [0 1 0];
            app.NofileloadedLampLabel.Text = 'Electrode loaded';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeSortingDisplayUIFigure = uifigure('Visible', 'off');
            app.SpikeSortingDisplayUIFigure.Name = 'SAMS';
            app.SpikeSortingDisplayUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.SpikeSortingDisplayUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup2 = uitabgroup(app.LeftPanel);
            app.FileTab = uitab(app.TabGroup2);
            app.FileTab.Title = 'File';
            app.excelfilePanel = uipanel(app.FileTab);
            app.excelfilePanel.Title = 'excel file';
            app.excelfileselectionButton = uibutton(app.excelfilePanel, 'push');
            app.excelfileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @excelfileselectionButtonPushed, true);
            app.excelfileselectionButton.Text = 'excel file selection';
            app.excelfileTextAreaLabel = uilabel(app.excelfilePanel);
            app.excelfileTextAreaLabel.Text = 'excel file';
            app.excelfileTextArea = uitextarea(app.excelfilePanel);
            app.excelfileTextArea.ValueChangingFcn = createCallbackFcn(app, @excelfileTextAreaValueChanging, true);
            app.spkFilePanel = uipanel(app.FileTab);
            app.spkFilePanel.Title = 'spk file';
            app.spkfileselectionButton = uibutton(app.spkFilePanel, 'push');
            app.spkfileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @spkfileselectionButtonPushed, true);
            app.spkfileselectionButton.Text = 'spk file selection';
            app.spkfileLabel = uilabel(app.spkFilePanel);
            app.spkfileLabel.Text = 'spk file';
            app.spkfilefolderTextArea = uitextarea(app.spkFilePanel);
            app.spkfilefolderTextArea.ValueChangingFcn = createCallbackFcn(app, @spkfilefolderTextAreaValueChanging, true);
            app.matfilePanel = uipanel(app.FileTab);
            app.matfilePanel.Title = 'mat file';
            app.matfileselectionButton = uibutton(app.matfilePanel, 'push');
            app.matfileselectionButton.ButtonPushedFcn = createCallbackFcn(app, @matfileselectionButtonPushed, true);
            app.matfileselectionButton.Text = 'mat file selection';
            app.matfileTextAreaLabel = uilabel(app.matfilePanel);
            app.matfileTextAreaLabel.Text = 'mat file';
            app.matfileTextArea = uitextarea(app.matfilePanel);
            app.loadfilesButton = uibutton(app.FileTab, 'push');
            app.loadfilesButton.ButtonPushedFcn = createCallbackFcn(app, @loadfilesButtonPushed, true);
            app.loadfilesButton.Text = 'load files';
            app.exportButton = uibutton(app.FileTab, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.SortingTab = uitab(app.TabGroup2);
            app.SortingTab.Title = 'Sorting';
            app.electrodeDropDownLabel = uilabel(app.SortingTab);
            app.electrodeDropDownLabel.Text = 'electrode';
            app.electrodeDropDown = uidropdown(app.SortingTab);
            app.electrodeDropDown.ValueChangedFcn = createCallbackFcn(app, @electrodeDropDownValueChanged, true);
            app.SortingElectrodeControls = uipanel(app.SortingTab);
            app.ReloadElectrodeButton = uibutton(app.SortingElectrodeControls, 'push');
            app.ReloadElectrodeButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadElectrodeButtonPushed, true);
            app.ReloadElectrodeButton.Text = 'Reload Electrode';
            app.unit1CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit1CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit1CheckBox.Text = 'unit 1';
            app.unit2CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit2CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit2CheckBox.Text = 'unit 2';
            app.unit3CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit3CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit3CheckBox.Text = 'unit 3';
            app.unit4CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit4CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit4CheckBox.Text = 'unit 4';
            app.unit5CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit5CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit5CheckBox.Text = 'unit 5';
            app.unit6CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit6CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit6CheckBox.Text = 'unit 6';
            app.unit7CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit7CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit7CheckBox.Text = 'unit 7';
            app.unit8CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit8CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit8CheckBox.Text = 'unit 8';
            app.unit9CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit9CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit9CheckBox.Text = 'unit 9';
            app.unit10CheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unit10CheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unit10CheckBox.Text = 'unit 10';
            app.unsortedCheckBox = uicheckbox(app.SortingElectrodeControls);
            app.unsortedCheckBox.ValueChangedFcn = createCallbackFcn(app, @unit1CheckBoxValueChanged, true);
            app.unsortedCheckBox.Text = 'unsorted';
            app.SortingUnitControls = uipanel(app.SortingTab);
            app.selectwaveformsButton = uibutton(app.SortingUnitControls, 'push');
            app.selectwaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @selectwaveformsButtonPushed, true);
            app.selectwaveformsButton.Text = 'select waveforms';
            app.actionButtonGroup = uibuttongroup(app.SortingUnitControls);
            app.actionButtonGroup.Title = 'action';
            app.addunitButton = uibutton(app.actionButtonGroup, 'push');
            app.addunitButton.ButtonPushedFcn = createCallbackFcn(app, @addunitButtonPushed, true);
            app.addunitButton.Text = 'add unit';
            app.excludeButton = uibutton(app.actionButtonGroup, 'push');
            app.excludeButton.ButtonPushedFcn = createCallbackFcn(app, @excludeButtonPushed, true);
            app.excludeButton.Text = 'exclude';
            app.clearselectedwaveformsButton = uibutton(app.SortingUnitControls, 'push');
            app.clearselectedwaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @clearselectedwaveformsButtonPushed, true);
            app.clearselectedwaveformsButton.Text = 'clear selected waveforms';
            app.selectfromPCAButton = uibutton(app.SortingUnitControls, 'push');
            app.selectfromPCAButton.ButtonPushedFcn = createCallbackFcn(app, @selectfromPCAButtonPushed, true);
            app.selectfromPCAButton.Text = 'select from PCA';
            app.ParametersTab = uitab(app.TabGroup2);
            app.ParametersTab.Title = 'Parameters';
            app.Panel2 = uipanel(app.ParametersTab);
            app.Panel2.Title = 'Spike Sorting Parameter Adjustment';
            app.threshold_to_mergeTextAreaLabel = uilabel(app.Panel2);
            app.threshold_to_mergeTextAreaLabel.Text = 'threshold_to_merge';
            app.threshold_to_mergeTextArea = uitextarea(app.Panel2);
            app.threshold_to_mergeTextArea.ValueChangingFcn = createCallbackFcn(app, @threshold_to_mergeTextAreaValueChanging, true);
            app.threshold_to_mergeTextArea.Value = {'1.5'};
            app.refractoryTsTextAreaLabel = uilabel(app.Panel2);
            app.refractoryTsTextAreaLabel.Text = 'refractoryT (s)';
            app.refractoryTsTextArea = uitextarea(app.Panel2);
            app.refractoryTsTextArea.ValueChangingFcn = createCallbackFcn(app, @refractoryTsTextAreaValueChanging, true);
            app.refractoryTsTextArea.Value = {'0.0015'};
            app.cutofffrequencyHzEditFieldLabel = uilabel(app.Panel2);
            app.cutofffrequencyHzEditFieldLabel.Text = 'cutoff frequency (Hz)';
            app.cutofffrequencyHzEditField = uieditfield(app.Panel2, 'numeric');
            app.cutofffrequencyHzEditField.Value = 0.1;
            app.standarddeviationcutoffEditFieldLabel = uilabel(app.Panel2);
            app.standarddeviationcutoffEditFieldLabel.Text = 'standard deviation cutoff';
            app.standarddeviationcutoffEditField = uieditfield(app.Panel2, 'numeric');
            app.standarddeviationcutoffEditField.Value = 3;
            app.flagthresholdEditFieldLabel = uilabel(app.Panel2);
            app.flagthresholdEditFieldLabel.Text = 'flag threshold';
            app.flagthresholdEditField = uieditfield(app.Panel2, 'numeric');
            app.flagthresholdEditField.Value = 0.2;
            app.SingleElectrodeBurstsPanel = uipanel(app.ParametersTab);
            app.SingleElectrodeBurstsPanel.Title = 'Single Electrode Bursts';
            app.minofspikeselectrodeLabel = uilabel(app.SingleElectrodeBurstsPanel);
            app.minofspikeselectrodeLabel.Text = 'min #  of spikes (electrode)';
            app.minofspikeselectrodeEditField = uieditfield(app.SingleElectrodeBurstsPanel, 'numeric');
            app.minofspikeselectrodeEditField.Value = 5;
            app.maxISImselectrodeEditFieldLabel = uilabel(app.SingleElectrodeBurstsPanel);
            app.maxISImselectrodeEditFieldLabel.Text = 'max ISI (ms) (electrode)';
            app.maxISImselectrodeEditField = uieditfield(app.SingleElectrodeBurstsPanel, 'numeric');
            app.maxISImselectrodeEditField.Value = 100;
            app.NetworkBurstsPanel = uipanel(app.ParametersTab);
            app.NetworkBurstsPanel.Title = 'Network Bursts';
            app.minofspikesEditFieldLabel = uilabel(app.NetworkBurstsPanel);
            app.minofspikesEditFieldLabel.Text = 'min # of spikes';
            app.minofspikesEditField = uieditfield(app.NetworkBurstsPanel, 'numeric');
            app.minofspikesEditField.Value = 50;
            app.maxISImsEditFieldLabel = uilabel(app.NetworkBurstsPanel);
            app.maxISImsEditFieldLabel.Text = 'max ISI (ms)';
            app.maxISImsEditField = uieditfield(app.NetworkBurstsPanel, 'numeric');
            app.maxISImsEditField.Value = 100;
            app.minelectrodesparticipatingEditFieldLabel = uilabel(app.NetworkBurstsPanel);
            app.minelectrodesparticipatingEditFieldLabel.Text = 'min electrodes participating';
            app.minelectrodesparticipatingEditField = uieditfield(app.NetworkBurstsPanel, 'numeric');
            app.minelectrodesparticipatingEditField.Value = 0.35;
            app.applyButton = uibutton(app.ParametersTab, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.NofileloadedLampLabel = uilabel(app.LeftPanel);
            app.NofileloadedLampLabel.Text = 'No file loaded';
            app.NofileloadedLamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_spikes = uiaxes(app.RightPanel);
            title(app.UIAxes_spikes, 'all waveforms')
            xlabel(app.UIAxes_spikes, 'time')
            ylabel(app.UIAxes_spikes, 'voltage')
            app.UIAxes_PCA = uiaxes(app.RightPanel);
            title(app.UIAxes_PCA, 'PCA')
            xlabel(app.UIAxes_PCA, 'principal component 1')
            ylabel(app.UIAxes_PCA, 'principal component 2')
            zlabel(app.UIAxes_PCA, 'Z')
            app.UIAxes_selection = uiaxes(app.RightPanel);
            title(app.UIAxes_selection, 'selected units')
            xlabel(app.UIAxes_selection, 'time')
            ylabel(app.UIAxes_selection, 'voltage')
            zlabel(app.UIAxes_selection, 'Z')
            app.UIAxes_selectedWaveforms = uiaxes(app.RightPanel);
            title(app.UIAxes_selectedWaveforms, 'selected waveforms')
            xlabel(app.UIAxes_selectedWaveforms, 'time')
            ylabel(app.UIAxes_selectedWaveforms, 'voltage')
            zlabel(app.UIAxes_selectedWaveforms, 'Z')
            % Show the figure after all components are created
            app.SpikeSortingDisplayUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manual_sorting_03252025

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpikeSortingDisplayUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeSortingDisplayUIFigure)
        end
    end
end